classdef InferencePaperApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NetworkParametersandGroundTruthTab  matlab.ui.container.Tab
        ROCUnknown                      matlab.ui.control.UIAxes
        TruePrecisionDistributions      matlab.ui.control.UIAxes
        Heatmap                         matlab.ui.control.UIAxes
        ROCKnown                        matlab.ui.control.UIAxes
        SynapticMagnitudeSliderLabel    matlab.ui.control.Label
        SynapticMagnitudeSlider         matlab.ui.control.Slider
        SynapticVariabilitySliderLabel  matlab.ui.control.Label
        SynapticVariabilitySlider       matlab.ui.control.Slider
        RandomGraphModelDropDownLabel   matlab.ui.control.Label
        RandomGraphModelDropDown        matlab.ui.control.DropDown
        GenerateButton                  matlab.ui.control.Button
        NumberofNeuronsEditFieldLabel   matlab.ui.control.Label
        NumberofNeuronsEditField        matlab.ui.control.NumericEditField
        ConnectionProbabilitySliderLabel  matlab.ui.control.Label
        ConnectionProbabilitySlider     matlab.ui.control.Slider
        ExcitatoryRatioSliderLabel      matlab.ui.control.Label
        ExcitatoryRatioSlider           matlab.ui.control.Slider
        SynapticRatiosEditFieldLabel_2  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_3  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_4  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_5  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_6  matlab.ui.control.Label
        SynapticRatiosEE                matlab.ui.control.NumericEditField
        SynapticRatiosIE                matlab.ui.control.NumericEditField
        SynapticRatiosEI                matlab.ui.control.NumericEditField
        SimulationTab                   matlab.ui.container.Tab
        AnalysisTab                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        Network; % Random graph of synaptic connectivity
        
        W
        Precision
        BiDirIds
    end
    
    methods (Access = private)
        
        function GenerateERNetwork(app)
            N = app.NumberofNeuronsEditField.Value;
            Ne = round(N*app.ExcitatoryRatioSlider.Value);
            Ni = N - Ne;
            
            psi = [app.SynapticRatiosEE.Value(1), -app.SynapticRatiosEI.Value(1) ; ...
                   app.SynapticRatiosIE.Value(1), -1];
            Jm = app.SynapticMagnitudeSlider.Value * psi / sqrt(N);
            Vm = app.SynapticVariabilitySlider.Value * psi.^2 / N;
            sm = sqrt(Vm);
            
            P = app.ConnectionProbabilitySlider.Value * ones(2);
            
            if (sm(1,1) ~= 0) % use first element to decide
                Jmee_dist = makedist('normal',Jm(1,1),sm(1,1));
                Jmee_tdist = truncate(Jmee_dist,0,Inf); % Dale's Law
                Jmei_dist = makedist('normal',Jm(1,2),sm(1,2));
                Jmei_tdist = truncate(Jmei_dist,-Inf,0); % Dale's Law
                Jmie_dist = makedist('normal',Jm(2,1),sm(2,1));
                Jmie_tdist = truncate(Jmie_dist,0,Inf); % Dale's Law
                Jmii_dist = makedist('normal',Jm(2,2),sm(2,2));
                Jmii_tdist = truncate(Jmii_dist,-Inf,0); % Dale's Law
        
                J = [sparse(random(Jmee_tdist,[Ne Ne]).*binornd(1,P(1,1),Ne,Ne)) ...
                     sparse(random(Jmei_tdist,[Ne Ni]).*binornd(1,P(1,2),Ne,Ni)); ...
                     sparse(random(Jmie_tdist,[Ni Ne]).*binornd(1,P(2,1),Ni,Ne)) ...
                     sparse(random(Jmii_tdist,[Ni Ni]).*binornd(1,P(2,2),Ni,Ni))];
            else
                J = [Jm(1,1) * binornd(1,P(1,1),Ne,Ne) ...
                     Jm(1,2) * binornd(1,P(1,2),Ne,Ni); ...
                     Jm(2,1) * binornd(1,P(2,1),Ni,Ne) ...
                     Jm(2,2) * binornd(1,P(2,2),Ni,Ni)];
            end
            
            J(1:(N+1):end) = 0; % Remove self-loops
            
            app.Network = J;
            app.W = J; % needs to be generalized
            app.Precision = app.W + app.W' - (app.W'*app.W);
            GetBiDirIds(app);
        end


        % Helper function for updating heatmap plot
        function PlotHeatmap(app)
            nHeatMapPlot = min([app.NumberofNeuronsEditField.Value 1e2]);
            
            if (nHeatMapPlot == 1e2)
                imagesc(app.Heatmap,app.Network([1:80 (end-19):end],[1:80 (end-19):end]))
                colormap(app.Heatmap,'hot')
                h = colorbar(app.Heatmap);
            else
                imagesc(app.Heatmap,app.Network(1:nHeatMapPlot,1:nHeatMapPlot))
                colormap(app.Heatmap,'hot')
                h = colorbar(app.Heatmap);
            end
            
            %app.Heatmap.
            app.Heatmap.XLim = [1 nHeatMapPlot];
            app.Heatmap.YLim = [1 nHeatMapPlot];
            %ylabel(h, 'Synaptic Strength')
            
            %colorTitleHandle = get(app.Heatmap,'Title');
            %titleString = 'Synaptic Strength';
            %set(colorTitleHandle ,'String',titleString);
            
            lw = 2;
            fz = 16;
            app.Heatmap.Box = 'off';
            app.Heatmap.LineWidth = lw;
            app.Heatmap.FontSize = fz;
        end


        % Helper function for updating precision distributions plot
        function PlotPrecisionDistributions(app)
            Colors = {[0 0.6 0], ... % EE1
                      [0 0.8 0], ... % EE2
                      [0.3010 0.7450 0.9330], ... % IE
                      [0.6 0 0], ... % II1
                      [0.8 0 0], ... % II2
                      [0.4940 0.1840 0.5560], ... % EI
                      [0 0.4470 0.7410]}; % EIIE
            
            SubTypeMeans = GetEISubTypeMeans(app);
            SubTypeStds = GetEISubTypeStds(app);
            
            % Plot
            lw = 2;
            fz = 16;
            
            numIncr = 1e3;
            
            legendNames = cell(1,1); ii = 1;
            handles = [];
            
            % assumes EE2 has elements...
            XX_EE = linspace(SubTypeMeans.EE0-5*SubTypeStds.EE0, SubTypeMeans.EE2+5*SubTypeStds.EE2, numIncr);
            
            % assumes II2 has elements...
            XX_II = linspace(SubTypeMeans.II2-5*SubTypeStds.II2, SubTypeMeans.II0+5*SubTypeStds.II0, numIncr);
            
            % assumes EE2 has elements...
            XX_EIIE = linspace(SubTypeMeans.EI-5*SubTypeStds.EI, SubTypeMeans.IE+5*SubTypeStds.IE, numIncr);
        
            if(any( SubTypeMeans.EE0 ))
                EE0Gauss = normpdf(XX_EE,SubTypeMeans.EE0,SubTypeStds.EE0);
                
                %subplot(1,3,1)
                h1 = plot(app.TruePrecisionDistributions,XX_EE,EE0Gauss, 'k', 'Linewidth',lw);
                hold(app.TruePrecisionDistributions)
                legendNames{ii} = 'e,i $\not\rightarrow$ e,i';
                handles(ii) = h1;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.EE1 ))
                EE1Gauss = normpdf(XX_EE,SubTypeMeans.EE1,SubTypeStds.EE1);
                
                %subplot(1,3,1)
                h2 = plot(app.TruePrecisionDistributions,XX_EE,EE1Gauss, 'Color',Colors{1}, 'Linewidth',lw);
                legendNames{ii} = 'e $\rightarrow$ e';
                handles(ii) = h2;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.EE2 ))
                EE2Gauss = normpdf(XX_EE,SubTypeMeans.EE2,SubTypeStds.EE2);
                
                %subplot(1,3,1)
                h3 = plot(app.TruePrecisionDistributions,XX_EE,EE2Gauss, 'Color',Colors{2}, 'Linewidth',lw);
                legendNames{ii} = 'e $\leftrightarrow$ e';
                handles(ii) = h3;
                ii = ii + 1;
            end
            
            %box off
            %set(gca,'LineWidth',lw)
            %set(gca,'FontSize',fz)
            
            if(any( SubTypeMeans.II0 ))
                II0Gauss = normpdf(XX_II,SubTypeMeans.II0,SubTypeStds.II0);
                
                plot(app.TruePrecisionDistributions,XX_II,II0Gauss, 'k', 'Linewidth',lw);
            end
            
            if(any( SubTypeMeans.II1 ))
                II1Gauss = normpdf(XX_II,SubTypeMeans.II1,SubTypeStds.II1);
                
                %subplot(1,3,2)
                h5 = plot(app.TruePrecisionDistributions,XX_II,II1Gauss, 'Color',Colors{4}, 'Linewidth',lw);

                legendNames{ii} = 'i $\rightarrow$ i';
                handles(ii) = h5;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.II2 ))
                II2Gauss = normpdf(XX_II,SubTypeMeans.II2,SubTypeStds.II2);
                
                %subplot(1,3,2)
                h6 = plot(app.TruePrecisionDistributions,XX_II,II2Gauss, 'Color',Colors{5}, 'Linewidth',lw);

                legendNames{ii} = 'i $\leftrightarrow$ i';
                handles(ii) = h6;
                ii = ii + 1;
            end
            
           % box off
            %set(gca,'LineWidth',lw)
            %set(gca,'FontSize',fz)
            
            if(any( SubTypeMeans.EIIE0 ))
                EIIE0Gauss = normpdf(XX_EIIE,SubTypeMeans.EIIE0,SubTypeStds.EIIE0);
                
                %subplot(1,3,3)
                plot(app.TruePrecisionDistributions,XX_EIIE,EIIE0Gauss, 'k', 'Linewidth',lw);

            end
            
            if(any( SubTypeMeans.EI ))
                EIGauss = normpdf(XX_EIIE,SubTypeMeans.EI,SubTypeStds.EI);
                
                %subplot(1,3,3)
                h8 = plot(app.TruePrecisionDistributions,XX_EIIE,EIGauss, 'Color',Colors{3}, 'Linewidth',lw);

                legendNames{ii} = 'e $\rightarrow$ i';
                handles(ii) = h8;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.IE ))
                IEGauss = normpdf(XX_EIIE,SubTypeMeans.IE,SubTypeStds.IE);
                
                %subplot(1,3,3)
                h9 = plot(app.TruePrecisionDistributions,XX_EIIE,IEGauss, 'Color',Colors{6}, 'Linewidth',lw);

                legendNames{ii} = 'i $\rightarrow$ e';
                handles(ii) = h9;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.EIIE ))
                EIIEGauss = normpdf(XX_EIIE,SubTypeMeans.EIIE,SubTypeStds.EIIE);
                
                %subplot(1,3,3)
                h10 = plot(app.TruePrecisionDistributions,XX_EIIE,EIIEGauss, 'Color',Colors{7}, 'Linewidth',lw);

                legendNames{ii} = 'e $\leftrightarrow$ i';
                handles(ii) = h10;
                ii = ii + 1;
            end
            
            app.TruePrecisionDistributions.Box = 'off';
            app.TruePrecisionDistributions.LineWidth = lw;
            app.TruePrecisionDistributions.FontSize = fz;
            
            %[~,objh] = legend(app.TruePrecisionDistributions,handles,legendNames, 'interpreter','latex', 'orientation','horizontal');
            %objhl = findobj(objh, 'type', 'line'); %// objects of legend of type line
            %set(objhl, 'Markersize', 30);
        end


        % BiDirIds = GetBiDirIds(W,N,Ne)
        %
        % For a blockwise directed adjacency matrix W, return the string Ids
        % of values resulting from the equation
        %       W + W'
        % and thus corresponding to one of 8 groups:
        %       'EE1'-'EE2'-'II1'-'II2'-'EI'-'IE'-'EIIE'-'Z'
        % where 1 or 2 represents unidirectional and bidirectional connections.
        
        % This function is mostly for use on randomly weighted adjacencies, since
        % the 8 groups under static weights form deterministic values...
        
        function GetBiDirIds(app)
            
            temp = 1*(app.W ~= 0);
            
            N = app.NumberofNeuronsEditField.Value;
            Ne = round(N*app.ExcitatoryRatioSlider.Value);
            
            Eids = 1:Ne;
            Iids = (Ne+1):N;
            
            temp(Eids,Eids) = temp(Eids,Eids) * 1;
            temp(Eids,Iids) = temp(Eids,Iids) * 3;
            temp(Iids,Eids) = temp(Iids,Eids) * 5;
            temp(Iids,Iids) = temp(Iids,Iids) * 7;
            
            temp2 = temp + temp';
            temp2( logical(tril(ones(N))) ) = nan;
            
            temp = strings(N,N);
            
            temp(temp2 == 1) = 'EE1';
            temp(temp2 == 2) = 'EE2';
            temp(temp2 == 7) = 'II1';
            temp(temp2 == 14) = 'II2';
            temp(temp2 == 3) = 'EI';
            temp(temp2 == 5) = 'IE';
            temp(temp2 == 8) = 'EIIE';
            
            % assign different zero groups
            Ni = N - Ne;
            IEZMat = nan(Ne,Ni);
            IIZMat = nan(Ni);
            EEZMat = nan(Ne);
            temp2EE = [temp2(Eids,Eids) IEZMat ; IEZMat' IIZMat];
            temp2EI = [EEZMat temp2(Eids,Iids) ; IEZMat' IIZMat];
            temp2II = [EEZMat IEZMat ; IEZMat' temp2(Iids,Iids)];
            temp(temp2EE == 0) = 'EE0';
            temp(temp2EI == 0) = 'EIIE0';
            temp(temp2II == 0) = 'II0';
            
            app.BiDirIds = temp;
        end


        % Assumes 2 pop E/I
        
        function SubTypeMeans = GetEISubTypeMeans(app)
            
            EE0ids = strcmp(app.BiDirIds,'EE0');
            EE1ids = strcmp(app.BiDirIds,'EE1');
            EE2ids = strcmp(app.BiDirIds,'EE2');
            
            II0ids = strcmp(app.BiDirIds,'II0');
            II1ids = strcmp(app.BiDirIds,'II1');
            II2ids = strcmp(app.BiDirIds,'II2');
            
            EIIE0ids = strcmp(app.BiDirIds,'EIIE0');
            EIids = strcmp(app.BiDirIds,'EI');
            IEids = strcmp(app.BiDirIds,'IE');
            EIIEids = strcmp(app.BiDirIds,'EIIE');
            
            SubTypeMeans = struct();
            
            Mat = app.Precision;
            
            if ( any(EE0ids(:)) )
                Mat_EE0 = Mat(EE0ids);
                SubTypeMeans.EE0 = mean(Mat_EE0);
            end
            
            if ( any(EE1ids(:)) )
                Mat_EE1 = Mat(EE1ids);
                SubTypeMeans.EE1 = mean(Mat_EE1);
            end
            
            if ( any(EE2ids(:)) )
                Mat_EE2 = Mat(EE2ids);
                SubTypeMeans.EE2 = mean(Mat_EE2);
            end
        
            if ( any(II0ids(:)) )
                Mat_II0 = Mat(II0ids);
                SubTypeMeans.II0 = mean(Mat_II0);
            end
            
            if ( any(II1ids(:)) )
                Mat_II1 = Mat(II1ids);
                SubTypeMeans.II1 = mean(Mat_II1);
            end
            
            if ( any(II2ids(:)) )
                Mat_II2 = Mat(II2ids);
                SubTypeMeans.II2 = mean(Mat_II2);
            end
            
            if ( any(EIIE0ids(:)) )
                Mat_EIIE0 = Mat(EIIE0ids);
                SubTypeMeans.EIIE0 = mean(Mat_EIIE0);
            end
            
            if ( any(EIids(:)) )
                Mat_EI = Mat(EIids);
                SubTypeMeans.EI = mean(Mat_EI);
            end
            
            if ( any(IEids(:)) )
                Mat_IE = Mat(IEids);
                SubTypeMeans.IE = mean(Mat_IE);
            end
        
            if ( any(EIIEids(:)) )
                Mat_EIIE = Mat(EIIEids);
                SubTypeMeans.EIIE = mean(Mat_EIIE);
            end
        end
        
        
        % Assumes 2 pop E/I
        
        function SubTypeMeans = GetEISubTypeStds(app)
            
            EE0ids = strcmp(app.BiDirIds,'EE0');
            EE1ids = strcmp(app.BiDirIds,'EE1');
            EE2ids = strcmp(app.BiDirIds,'EE2');
            
            II0ids = strcmp(app.BiDirIds,'II0');
            II1ids = strcmp(app.BiDirIds,'II1');
            II2ids = strcmp(app.BiDirIds,'II2');
            
            EIIE0ids = strcmp(app.BiDirIds,'EIIE0');
            EIids = strcmp(app.BiDirIds,'EI');
            IEids = strcmp(app.BiDirIds,'IE');
            EIIEids = strcmp(app.BiDirIds,'EIIE');
            
            SubTypeMeans = struct();
            
            Mat = app.Precision;
            
            if ( any(EE0ids(:)) )
                Mat_EE0 = Mat(EE0ids);
                SubTypeMeans.EE0 = std(Mat_EE0);
            end
            
            if ( any(EE1ids(:)) )
                Mat_EE1 = Mat(EE1ids);
                SubTypeMeans.EE1 = std(Mat_EE1);
            end
            
            if ( any(EE2ids(:)) )
                Mat_EE2 = Mat(EE2ids);
                SubTypeMeans.EE2 = std(Mat_EE2);
            end
        
            if ( any(II0ids(:)) )
                Mat_II0 = Mat(II0ids);
                SubTypeMeans.II0 = std(Mat_II0);
            end
            
            if ( any(II1ids(:)) )
                Mat_II1 = Mat(II1ids);
                SubTypeMeans.II1 = std(Mat_II1);
            end
            
            if ( any(II2ids(:)) )
                Mat_II2 = Mat(II2ids);
                SubTypeMeans.II2 = std(Mat_II2);
            end
            
            if ( any(EIIE0ids(:)) )
                Mat_EIIE0 = Mat(EIIE0ids);
                SubTypeMeans.EIIE0 = std(Mat_EIIE0);
            end
            
            if ( any(EIids(:)) )
                Mat_EI = Mat(EIids);
                SubTypeMeans.EI = std(Mat_EI);
            end
            
            if ( any(IEids(:)) )
                Mat_IE = Mat(IEids);
                SubTypeMeans.IE = std(Mat_IE);
            end
        
            if ( any(EIIEids(:)) )
                Mat_EIIE = Mat(EIIEids);
                SubTypeMeans.EIIE = std(Mat_EIIE);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              
            % Resize window with dynamic resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.5;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
            
            % Generate default network and plots
            switch app.RandomGraphModelDropDown.Value
                case 'Erdos-Renyi'
                    GenerateERNetwork(app);
            end
            
            PlotHeatmap(app)
            PlotPrecisionDistributions(app)
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            switch app.RandomGraphModelDropDown.Value
                case 'Erdos-Renyi'
                    GenerateERNetwork(app);
            end
            
            PlotHeatmap(app)
            PlotPrecisionDistributions(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NetworkParametersandGroundTruthTab = uitab(app.TabGroup);
            app.NetworkParametersandGroundTruthTab.Title = 'Network Parameters and Ground Truth';
            app.ROCUnknown = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.ROCUnknown, 'ROC - Unknown Cell Types')
            xlabel(app.ROCUnknown, 'X')
            ylabel(app.ROCUnknown, 'Y')
            app.TruePrecisionDistributions = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.TruePrecisionDistributions, 'True Precision Distributions')
            xlabel(app.TruePrecisionDistributions, 'Precision')
            ylabel(app.TruePrecisionDistributions, 'Density')
            app.Heatmap = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.Heatmap, 'Heatmap of Connectivity')
            xlabel(app.Heatmap, 'Neuron Index')
            ylabel(app.Heatmap, 'Neuron Index')
            app.ROCKnown = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.ROCKnown, 'ROC - Known Cell Types')
            xlabel(app.ROCKnown, 'X')
            ylabel(app.ROCKnown, 'Y')
            app.SynapticMagnitudeSliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticMagnitudeSliderLabel.Text = 'Synaptic Magnitude';
            app.SynapticMagnitudeSlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.SynapticMagnitudeSlider.Limits = [0 10];
            app.SynapticMagnitudeSlider.Value = 2.5;
            app.SynapticVariabilitySliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticVariabilitySliderLabel.Text = 'Synaptic Variability';
            app.SynapticVariabilitySlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.SynapticVariabilitySlider.Limits = [0 1];
            app.SynapticVariabilitySlider.Value = 6.24e-05;
            app.RandomGraphModelDropDownLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.RandomGraphModelDropDownLabel.Text = 'Random Graph Model';
            app.RandomGraphModelDropDown = uidropdown(app.NetworkParametersandGroundTruthTab);
            app.RandomGraphModelDropDown.Items = {'Erdos-Renyi', 'Spatial'};
            app.RandomGraphModelDropDown.Value = 'Erdos-Renyi';
            app.GenerateButton = uibutton(app.NetworkParametersandGroundTruthTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.NumberofNeuronsEditFieldLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.NumberofNeuronsEditFieldLabel.Text = 'Number of Neurons';
            app.NumberofNeuronsEditField = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.NumberofNeuronsEditField.Value = 100;
            app.ConnectionProbabilitySliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.ConnectionProbabilitySliderLabel.Text = 'Connection Probability';
            app.ConnectionProbabilitySlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.ConnectionProbabilitySlider.Limits = [0 1];
            app.ConnectionProbabilitySlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.ConnectionProbabilitySlider.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.ConnectionProbabilitySlider.MinorTicks = [0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.ConnectionProbabilitySlider.Value = 0.2;
            app.ExcitatoryRatioSliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.ExcitatoryRatioSliderLabel.Text = 'Excitatory Ratio';
            app.ExcitatoryRatioSlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.ExcitatoryRatioSlider.Limits = [0 1];
            app.ExcitatoryRatioSlider.Value = 0.8;
            app.SynapticRatiosEditFieldLabel_2 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_2.Text = 'e';
            app.SynapticRatiosEditFieldLabel_3 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_3.Text = 'i';
            app.SynapticRatiosEditFieldLabel_4 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_4.Text = 'e';
            app.SynapticRatiosEditFieldLabel_5 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_5.Text = 'i';
            app.SynapticRatiosEditFieldLabel_6 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_6.Text = 'Synaptic Ratios';
            app.SynapticRatiosEE = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.SynapticRatiosEE.Value = 0.1;
            app.SynapticRatiosIE = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.SynapticRatiosIE.Value = 0.45;
            app.SynapticRatiosEI = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.SynapticRatiosEI.Value = 0.6;
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InferencePaperApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end